classdef fitnpls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SettingsMenu               matlab.ui.container.Menu
        DebugloggingMenu           matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        RuncontrolPanel            matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        Lamp_6                     matlab.ui.control.Lamp
        Lamp_5                     matlab.ui.control.Lamp
        Lamp_4                     matlab.ui.control.Lamp
        DoneLabel                  matlab.ui.control.Label
        ReadyLabel                 matlab.ui.control.Label
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        CrossvalidateButton        matlab.ui.control.Button
        FitButton                  matlab.ui.control.Button
        PreprocessButton           matlab.ui.control.Button
        SettingsPanel              matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        ofLVsfitDropDown_2         matlab.ui.control.DropDown
        ofLVsfitDropDownLabel_2    matlab.ui.control.Label
        ofLVsfitDropDown           matlab.ui.control.DropDown
        ofLVsfitDropDownLabel      matlab.ui.control.Label
        PredictorXDropDown         matlab.ui.control.DropDown
        PredictorXDropDownLabel    matlab.ui.control.Label
        PredictedYDropDown         matlab.ui.control.DropDown
        PredictedYDropDownLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        console                    matlab.ui.control.ListBox
        TabGroup                   matlab.ui.container.TabGroup
        PreprocessingTab           matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        ScalesamplesCheckBox       matlab.ui.control.CheckBox
        MeancentersamplesCheckBox  matlab.ui.control.CheckBox
        DataoverviewPanel          matlab.ui.container.Panel
        NPLSfittingTab             matlab.ui.container.Tab
        GridLayout9                matlab.ui.container.GridLayout
        nplsresults                matlab.ui.container.Panel
        CrossvalidationTab         matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data_original
        data_current % Description
        model
        crossval
        debuglogging {mustBeA(debuglogging,'logical')} = true
    end
    
    methods (Access = private)
        
        function updateconsole(app,message,type)
            arguments
                app
                message {mustBeText}
                type {mustBeMember(type,["message","warning","error","information"])} = "message"
            end
           
            switch type
                case 'message'
                    color=[0         0.4         0.2];
                    fw='normal';
                case 'warning'
                    color=[1         0.6           0];
                    fw='bold';
                case 'error'
                    color=[1         0.4         0.4];
                    fw='bold';
                case 'information'
                    color=[.6         0.6         0.6];
                    fw='normal';
            end

            
            n=numel(app.console.Items);
            n=n+1;
            time=char(datetime('now'));time=time(end-7:end);
            app.console.Items{1,end+1} = [num2str(n),':  ',time,': ',char(message)];
            s = uistyle(FontColor=color,FontWeight=fw);
            addStyle(app.console,s,"item",n)
            scroll(app.console, 'bottom');drawnow
        end
        
        function dataout = preprocess(app,data)

            dataout=data;
            switch app.PredictorXDropDown.Value
                case 'Fluorescence'
                    center = [0 0 0];
                    scale = [0 0 0];
                    if app.MeancentersamplesCheckBox.Value
                        center(1)=1;
                    end
                    if app.ScalesamplesCheckBox.Value
                        scale(1)=1;
                    end
        
                    [Xnew,mX,sX] = nway.tools.nprocess(data.X,center,scale);
                    if app.MeancentersamplesCheckBox.Value
                        %mX=mX{1};
                        %dataout.metadata.mX=mX;
                    end
                    if app.ScalesamplesCheckBox.Value
                        %sX=sX{1};
                        %dataout.metadata.SX=sX;
                    end
                    dataout.X=Xnew;
                case 'Absorbance'
                    center = [0 0];
                    scale = [0 0];
                    if app.MeancentersamplesCheckBox.Value
                        center(1)=1;
                    end
                    if app.ScalesamplesCheckBox.Value
                        scale(1)=1;
                    end

                    [Xnew,mX,sX] = nway.tools.nprocess(data.abs,center,scale);
                    if app.MeancentersamplesCheckBox.Value
                        %mX=mX{1};
                        %dataout.metadata.mX=mX;
                    end
                    if app.ScalesamplesCheckBox.Value
                        %sX=sX{1};
                        %dataout.metadata.SX=sX;
                    end
                    dataout.abs=Xnew;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            metafld=data.metadata.Properties.VariableNames;
            metafld=metafld(matches(data.metadata.Properties.VariableTypes,"double"));
            app.PredictedYDropDown.Items=metafld;
            if app.debuglogging,app.updateconsole('Metadata fields populated.','information'),end
            app.data_original=data;
            app.data_current=app.data_original;
            if app.debuglogging,app.updateconsole('Data assigned.','information'),end  
        end

        % Menu selected function: DebugloggingMenu
        function DebugloggingMenuSelected(app, event)
            app.debuglogging=app.DebugloggingMenu.Checked;
        end

        % Button pushed function: PreprocessButton
        function PreprocessingChanged(app, event)
            data = app.preprocess(app.data_original);
            app.data_current=data;

            app.updateconsole("Pre-processing (from original) carried out.")
            
        end

        % Button pushed function: FitButton
        function ComputeNpls(app, event)
            switch  app.PredictorXDropDown.Value
                case 'Fluorescence'
                    x=app.data_current.X;
                    regself=zeros(app.data_current.nEm,app.data_current.nEx);
                case 'Absorbance'
                    x=app.data_current.abs;
                    regself=zeros(size(app.data_current.abs,2),1);
            end
            
            y=app.data_current.metadata.(app.PredictedYDropDown.Value);
            
            miss=isnan(y);
            if all(isnan(y))
                app.updateconsole("all Y missing. Cannot proceed.","error")
                return
            end

            [Xfactors,Yfactors,Core,B,~,ssx,ssy,reg] = ...
                nway.models.npls(x(not(miss),:,:),y(not(miss)), ...
                str2double(app.ofLVsfitDropDown.Value));
            model=nplsmodel;
            model.LatentVariablesX=Xfactors;
            model.LatentVariablesY=Yfactors;
            model.Core=Core;
            model.B = B;

            ypred=nway.models.npred(x,str2double(app.ofLVsfitDropDown.Value),Xfactors,Yfactors,Core,B);

            model.ypred=ypred;
            model.ssx=ssx(:,1);
            model.ssy=ssy(:,1);
            model.percentExplainedX=ssx(:,2);
            model.percentExplainedY=ssy(:,2);
            model.reg=reg;
            app.model=model;
            app.updateconsole("N-PLS model fit.")


            %% Play with results for now
            t=tiledlayout(app.nplsresults);
            ax=nexttile(t);

            
            for j=1:str2double(app.ofLVsfitDropDown.Value)
                regself=regself+model.reg{j};
            end
            switch  app.PredictorXDropDown.Value
                case 'Fluorescence'
                    contourf(ax,app.data_current.Ex,app.data_current.Em,squeeze(median(app.data_current.X,1)).*regself)
                case 'Absorbance'
                    plot(ax,app.data_current.absWave,regself,LineWidth=1.4,Color='k')
            end
            
            ax=nexttile(t);
            scatter(ax,model.ypred,y,25,'k','filled')
            hold(ax,"on")
            refline(ax)
            refline(ax,1,0)
            ylabel(ax,'Measured Y')
            xlabel(ax,'Predicted Y')

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {579, 579};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.DebugloggingMenu = uimenu(app.SettingsMenu);
            app.DebugloggingMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugloggingMenuSelected, true);
            app.DebugloggingMenu.Checked = 'on';
            app.DebugloggingMenu.Text = 'Debug logging';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SettingsPanel = uipanel(app.GridLayout2);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout4 = uigridlayout(app.SettingsPanel);
            app.PredictedYDropDownLabel = uilabel(app.GridLayout4);
            app.PredictedYDropDownLabel.Text = 'Predicted (Y)';
            app.PredictedYDropDown = uidropdown(app.GridLayout4);
            app.PredictedYDropDown.Items = {''};
            app.PredictedYDropDown.Value = '';
            app.PredictorXDropDownLabel = uilabel(app.GridLayout4);
            app.PredictorXDropDownLabel.Text = 'Predictor (X)';
            app.PredictorXDropDown = uidropdown(app.GridLayout4);
            app.PredictorXDropDown.Items = {'Absorbance', 'Fluorescence'};
            app.PredictorXDropDown.Value = 'Fluorescence';
            app.ofLVsfitDropDownLabel = uilabel(app.GridLayout4);
            app.ofLVsfitDropDownLabel.Text = '# of LVs (fit)';
            app.ofLVsfitDropDown = uidropdown(app.GridLayout4);
            app.ofLVsfitDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ofLVsfitDropDown.Value = '1';
            app.ofLVsfitDropDownLabel_2 = uilabel(app.GridLayout4);
            app.ofLVsfitDropDownLabel_2.Text = '# of LVs (crossval)';
            app.ofLVsfitDropDown_2 = uidropdown(app.GridLayout4);
            app.ofLVsfitDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ofLVsfitDropDown_2.Value = '1';
            app.RuncontrolPanel = uipanel(app.GridLayout2);
            app.RuncontrolPanel.Title = 'Run control';
            app.GridLayout7 = uigridlayout(app.RuncontrolPanel);
            app.PreprocessButton = uibutton(app.GridLayout7, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingChanged, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.FitButton = uibutton(app.GridLayout7, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeNpls, true);
            app.FitButton.Text = 'Fit';
            app.CrossvalidateButton = uibutton(app.GridLayout7, 'push');
            app.CrossvalidateButton.Text = 'Cross-validate';
            app.Lamp = uilamp(app.GridLayout7);
            app.Lamp_2 = uilamp(app.GridLayout7);
            app.Lamp_3 = uilamp(app.GridLayout7);
            app.ReadyLabel = uilabel(app.GridLayout7);
            app.ReadyLabel.Text = 'Ready?';
            app.DoneLabel = uilabel(app.GridLayout7);
            app.DoneLabel.Text = 'Done?';
            app.Lamp_4 = uilamp(app.GridLayout7);
            app.Lamp_5 = uilamp(app.GridLayout7);
            app.Lamp_6 = uilamp(app.GridLayout7);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.GridLayout5 = uigridlayout(app.PreprocessingTab);
            app.DataoverviewPanel = uipanel(app.GridLayout5);
            app.DataoverviewPanel.Title = 'Data overview';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.MeancentersamplesCheckBox = uicheckbox(app.GridLayout6);
            app.MeancentersamplesCheckBox.Text = 'Mean-center (samples)';
            app.ScalesamplesCheckBox = uicheckbox(app.GridLayout6);
            app.ScalesamplesCheckBox.Text = 'Scale (samples)';
            app.NPLSfittingTab = uitab(app.TabGroup);
            app.NPLSfittingTab.Title = 'N-PLS fitting';
            app.GridLayout9 = uigridlayout(app.NPLSfittingTab);
            app.nplsresults = uipanel(app.GridLayout9);
            app.CrossvalidationTab = uitab(app.TabGroup);
            app.CrossvalidationTab.Title = 'Cross-validation';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.console = uilistbox(app.GridLayout8);
            app.console.Items = {''};
            app.console.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitnpls(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end